classdef MicroProjectAppTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MembersAyaRedaAbdallahSaiedAbdelazizMohammadLabel  matlab.ui.control.Label
        MicroprocessorProjectLabel  matlab.ui.control.Label
        ReadInputFileButton         matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        UserInput                   matlab.ui.control.TextArea
        InputTextAreaLabel          matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: UserInput
        function UserInputValueChanged(app, event)
         
            
        end

        % Callback function
        function HelperTextAreaValueChanged(app, event)

            
        end

        % Button pushed function: ReadInputFileButton
        function ReadInputFileButtonPushed(app, event)
               %Open config.json 
               json_filename = 'conf.json';
               json_config = jsondecode(fileread(json_filename));           
           
               % Open  input.txt file
               filename = 'input.txt';
               fileID = fopen(filename);
               formatspec = '%c';
               Array = fscanf(fileID,formatspec);                                     
               fclose(fileID);
               
               % Read Data
               Element1=dec2bin(Array([1]),8);        % convert intered charater into binary

               Element1 = str2double(Element1);       % make the binary (1,0) numbers not characters 

               % Check user input
               Protocol = string(app.UserInput.Value);
            
              if(Protocol=="USB")
                  
              %Remove Error Message   
              app.Label.Text = " ";

              
              elseif( Protocol == "UART")
                  
               %Remove Error Message  
               app.Label.Text = " ";
              
                % Data               
               data = num2str(Element1)-'0';                                      % seperate them with spaces to be an array 
               
               if(json_config(1).inputs.data_bits == 8 && length(data) == 7)      % make the data 8 bits
                     data = [ 0 data ] ;                
               elseif((json_config(1).inputs.data_bits == 8 && length(data) == 8)|| (json_config(1).inputs.data_bits == 7 && length(data) == 7))  
                   % do nth
               elseif(json_config(1).inputs.data_bits == 8 && length(data) == 6)  % make the data 8 bits
                     data = [0 0 data];     
                elseif(json_config(1).inputs.data_bits == 7 && length(data) == 6)  % make the data 7 bits
                     data = [0 data];                     
               end
               data = flip(data);                   % flip data to make LSB is the MSB and vice versa
               
                %Constants
               idle_bit = 1;
               start_bit = 0;
               parity_bit = 0;
               stop_bit = 1;
               
               idle_start_bit = [idle_bit start_bit];
               Onestop_Noparity_idle_bit = [stop_bit idle_bit];
               Twostop_Noparity_idle_bit = [stop_bit stop_bit idle_bit];
               Onestop_parity_idle_bit = [0 0 0];
               Twostop_parity_idle_bit = [0 0 0];
               
               %Frame
               frame= [ idle_start_bit data Onestop_Noparity_idle_bit ];


               if(json_config(1).inputs.stop_bits == 1 && json_config(1).inputs.parity == "none" )
                    frame = [ idle_start_bit data Onestop_Noparity_idle_bit];    
                    
               elseif (json_config(1).inputs.stop_bits == 2 && json_config(1).inputs.parity == "none")
                    frame = [ idle_start_bit data Twostop_Noparity_idle_bit];
                    
               elseif (json_config(1).inputs.stop_bits == 1 && (json_config(1).inputs.parity == "even"||json_config(1).inputs.parity == "odd"))
                   
                    number_of_ones = nnz(data);        %count number of ones in the data
                    if(json_config(1).inputs.parity == "even" && mod(number_of_ones,2) == 0)
                        parity_bit =0;
                    elseif ( (json_config(1).inputs.parity == "even" && mod(number_of_ones,2) ~= 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) == 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) ~= 0))
                        parity_bit =0;  
                    end
                 Onestop_parity_idle_bit = [parity_bit stop_bit  idle_bit];  
                 frame = [ idle_start_bit data Onestop_parity_idle_bit];
                         
               elseif (json_config(1).inputs.stop_bits == 2 && (json_config(1).inputs.parity == "even"||json_config(1).inputs.parity == "odd"))
                   
                      number_of_ones = nnz(data);        %count number of ones in the data
                    if(json_config(1).inputs.parity == "even" && mod(number_of_ones,2) == 0)
                        parity_bit =0;
                    elseif ( (json_config(1).inputs.parity == "even" && mod(number_of_ones,2) ~= 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) == 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) ~= 0))
                        parity_bit =0;  
                    end
                    Twostop_parity_idle_bit = [parity_bit stop_bit stop_bit  idle_bit];     
                    frame = [ idle_start_bit data Twostop_parity_idle_bit];                    
               end


               stairs (app.UIAxes,[frame,frame(end)]);         
             
          else
              app.Label.Text = "Undefined Protocol Entry";
              frame2 = [ 0 0 0 0];
              stairs (app.UIAxes,[frame2,frame2(end)]); 
          end
          
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frame')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.InputTextAreaLabel = uilabel(app.UIFigure);
            app.InputTextAreaLabel.Text = 'Input';
            app.UserInput = uitextarea(app.UIFigure);
            app.UserInput.ValueChangedFcn = createCallbackFcn(app, @UserInputValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' ';
            app.ReadInputFileButton = uibutton(app.UIFigure, 'push');
            app.ReadInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadInputFileButtonPushed, true);
            app.ReadInputFileButton.Text = 'Read Input File';
            app.MicroprocessorProjectLabel = uilabel(app.UIFigure);
            app.MicroprocessorProjectLabel.Text = 'Microprocessor Project';
            app.MembersAyaRedaAbdallahSaiedAbdelazizMohammadLabel = uilabel(app.UIFigure);
            app.MembersAyaRedaAbdallahSaiedAbdelazizMohammadLabel.Text = {'            Members:'; ''; '- Aya Reda'; ''; '- Abdallah Saied'; ''; '- Abdelaziz Mohammad'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MicroProjectAppTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end